classdef app_prarob < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ZpozLabel                     matlab.ui.control.Label
        YpozLabel                     matlab.ui.control.Label
        XpozLabel                     matlab.ui.control.Label
        OlovkaLabel                   matlab.ui.control.Label
        zglobLabel_2                  matlab.ui.control.Label
        zglobLabel                    matlab.ui.control.Label
        ZLabel                        matlab.ui.control.Label
        YLabel                        matlab.ui.control.Label
        XLabel                        matlab.ui.control.Label
        Kut3Label                     matlab.ui.control.Label
        Kut2Label                     matlab.ui.control.Label
        Kut1Label                     matlab.ui.control.Label
        IznosipozicijaLabel           matlab.ui.control.Label
        IznosikutevaLabel             matlab.ui.control.Label
        UnospozicijaLabel             matlab.ui.control.Label
        UnoskutevaLabel               matlab.ui.control.Label
        KutprvizglobSlider            matlab.ui.control.Slider
        KutprvizglobSliderLabel       matlab.ui.control.Label
        OlovkakutSlider               matlab.ui.control.Slider
        OlovkakutSliderLabel          matlab.ui.control.Label
        KutdrugizglobSlider           matlab.ui.control.Slider
        KutdrugizglobSliderLabel      matlab.ui.control.Label
        PotvrdaunosakutevaButton      matlab.ui.control.Button
        PotvrdaunosapozicijeButton    matlab.ui.control.Button
        ButtonGroup                   matlab.ui.container.ButtonGroup
        IskljucenoButton              matlab.ui.control.RadioButton
        AutonomnoButton               matlab.ui.control.RadioButton
        RucnoButton                   matlab.ui.control.RadioButton
        PozicijaolovkeEditField       matlab.ui.control.NumericEditField
        PozicijaolovkeEditFieldLabel  matlab.ui.control.Label
        OlovkakutEditField            matlab.ui.control.NumericEditField
        OlovkakutEditFieldLabel       matlab.ui.control.Label
        PozicijayEditField            matlab.ui.control.NumericEditField
        PozicijayEditFieldLabel       matlab.ui.control.Label
        PozicijaxEditField            matlab.ui.control.NumericEditField
        PozicijaxEditFieldLabel       matlab.ui.control.Label
        KutdrugizglobEditField        matlab.ui.control.NumericEditField
        KutdrugizglobEditFieldLabel   matlab.ui.control.Label
        KutprvizglobEditField         matlab.ui.control.NumericEditField
        KutprvizglobEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = public)
        SimulinkModelName; % Description
        Kut1
        Kut2
        Kut3
    end
    
    methods (Access = public)
    
    function setKut(app)
        set_param([app.SimulinkModelName, '/prvikut'], 'Value', num2str(app.KutprvizglobEditField.Value));
        set_param([app.SimulinkModelName, '/drugikut'], 'Value', num2str(app.KutdrugizglobEditField.Value));
        set_param([app.SimulinkModelName, '/trecikut'], 'Value', num2str(app.OlovkakutEditField.Value));
        
    end
        
    function UpdateValues(app, fields, newVal)
            for i = 1:numel(fields)
                app.(fields{i}).Value = newVal
            end
    end

        
    function UpdateText(app, fields, newVal)
            for i = 1:numel(fields)
                app.(fields{i}).Text = mat2str(newVal);
                drawnow;
            end
    end
    function UpdateK1(app, newVal)
        app.Kut1Label.Text = mat2str(newVal);
        drawnow;
    end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global hApp
            hApp = app
            
            app.SimulinkModelName = 'simulation_three_motors';
            ylimit = [-175, 175];
            xlimit = [0, 350];
            zlimit = [0, 141.75];
            app.PozicijaolovkeEditField.Limits = zlimit;
            app.PozicijaxEditField.Limits = xlimit;
            pp.PozicijayEditField.Limits = ylimit;
            angleLimit1 = [-150, 150];    %
            angleLimit2 = [-163, 150];    % 
            angleLimit3 = [0, 90];    %kut olovke
            app.KutprvizglobSlider.Limits = angleLimit1;
            app.KutdrugizglobSlider.Limits = angleLimit2;
            app.OlovkakutSlider.Limits = angleLimit3;
            app.KutprvizglobEditField.Limits = angleLimit1;
            app.KutdrugizglobEditField.Limits = angleLimit2;
            app.OlovkakutEditField.Limits = angleLimit3;
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton == app.AutonomnoButton
                app.KutprvizglobEditField.Enable = false;
                app.KutprvizglobSlider.Enable = false;
                app.KutdrugizglobEditField.Enable = false;
                app.KutdrugizglobSlider.Enable = false;
                app.OlovkakutEditField.Enable = false;
                app.OlovkakutSlider.Enable = false;

                app.PozicijaxEditField.Enable = false;
                app.PozicijayEditField.Enable = false;
                app.PozicijaolovkeEditField.Enable = false;

                app.PotvrdaunosakutevaButton.Enable = false;
                app.PotvrdaunosapozicijeButton.Enable = false;

                app.Kut1Label.Enable = true;
                app.Kut2Label.Enable = true;
                app.Kut3Label.Enable = true;
                app.XLabel.Enable = true;
                app.YLabel.Enable = true;
                app.ZLabel.Enable = true;
            end;

            if selectedButton == app.RucnoButton
                app.KutprvizglobEditField.Enable = true;
                app.KutprvizglobSlider.Enable = true;
                app.KutdrugizglobEditField.Enable = true;
                app.KutdrugizglobSlider.Enable = true;
                app.OlovkakutEditField.Enable = true;
                app.OlovkakutSlider.Enable = true;

                app.PozicijaxEditField.Enable = true;
                app.PozicijayEditField.Enable = true;
                app.PozicijaolovkeEditField.Enable = true;

                app.PotvrdaunosakutevaButton.Enable = true;
                app.PotvrdaunosapozicijeButton.Enable = true;

                app.Kut1Label.Enable = true;
                app.Kut2Label.Enable = true;
                app.Kut3Label.Enable = true;
                app.XLabel.Enable = true;
                app.YLabel.Enable = true;
                app.ZLabel.Enable = true;
            end;

            if selectedButton == app.IskljucenoButton
                app.KutprvizglobEditField.Enable = false;
                app.KutprvizglobSlider.Enable = false;
                app.KutdrugizglobEditField.Enable = false;
                app.KutdrugizglobSlider.Enable = false;
                app.OlovkakutEditField.Enable = false;
                app.OlovkakutSlider.Enable = false;

                app.PozicijaxEditField.Enable = false;
                app.PozicijayEditField.Enable = false;
                app.PozicijaolovkeEditField.Enable = false;

                app.PotvrdaunosakutevaButton.Enable = false;
                app.PotvrdaunosapozicijeButton.Enable = false;

                app.Kut1Label.Enable = false;
                app.Kut2Label.Enable = false;
                app.Kut3Label.Enable = false;
                app.XLabel.Enable = false;
                app.YLabel.Enable = false;
                app.ZLabel.Enable = false;
            end;
        end

        % Value changing function: KutprvizglobSlider
        function KutprvizglobSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UpdateValues({'KutprvizglobEditField'}, changingValue);
        end

        % Value changing function: KutdrugizglobSlider
        function KutdrugizglobSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UpdateValues({'KutdrugizglobEditField'}, changingValue);
        end

        % Value changing function: OlovkakutSlider
        function OlovkakutSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UpdateValues({'OlovkakutEditField'}, changingValue);
        end

        % Button pushed function: PotvrdaunosakutevaButton
        function PotvrdaunosakutevaButtonPushed(app, event)
            th1 = app.KutprvizglobEditField.Value;
            th2 = app.KutdrugizglobEditField.Value;
            th3 = app.OlovkakutEditField.Value;
            [x, y, z] = direct_kinematics(th1, th2, th3);
            if ((x < 150 && y < 75 && y > -75) || (z < 0 || z > 141.75) || (x<0 || x>350) || (y < -175 || y > 175))
                uialert(app.UIFigure, 'Nedozvoljena pozicija', 'pozicija vrha ulazi u zabranjeno podrucje');
            else
                 app.setKut();
            end;
        end

        % Value changed function: KutprvizglobEditField
        function KutprvizglobEditFieldValueChanged(app, event)
            value = app.KutprvizglobEditField.Value;
            app.UpdateValues({'KutprvizglobSlider'}, value);
        end

        % Value changed function: KutdrugizglobEditField
        function KutdrugizglobEditFieldValueChanged(app, event)
            value = app.KutdrugizglobEditField.Value;
            app.UpdateValues({'KutdrugizglobSlider'}, value);
        end

        % Value changed function: OlovkakutEditField
        function OlovkakutEditFieldValueChanged(app, event)
            value = app.OlovkakutEditField.Value;
            app.UpdateValues({'OlovkakutSlider'}, value);
        end

        % Button pushed function: PotvrdaunosapozicijeButton
        function PotvrdaunosapozicijeButtonPushed(app, event)
            x = app.PozicijaxEditField.Value;
            y = app.PozicijayEditField.Value;
            z = app.PozicijaolovkeEditField.Value;
            if (x < 150 && y < 75 && y > -75)
                uialert(app.UIFigure, 'Nedozvoljena pozicija', 'X i Y ulaze zabranjeno podrucje');
            else
                if (y >= 0)
                    [t1, t2, t3] = solve_thetas(x, y, z);
                else
                    [t1, t2, t3] = solve_thetas2(x, y, z);
                end
                set_param([app.SimulinkModelName, '/prvikut'], 'Value', num2str(t1));
                set_param([app.SimulinkModelName, '/drugikut'], 'Value', num2str(t2));
                set_param([app.SimulinkModelName, '/trecikut'], 'Value', num2str(t3));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.KutprvizglobEditFieldLabel = uilabel(app.UIFigure);
            app.KutprvizglobEditFieldLabel.Text = 'Kut prvi zglob';
            app.KutprvizglobEditField = uieditfield(app.UIFigure, 'numeric');
            app.KutprvizglobEditField.ValueChangedFcn = createCallbackFcn(app, @KutprvizglobEditFieldValueChanged, true);
            app.KutdrugizglobEditFieldLabel = uilabel(app.UIFigure);
            app.KutdrugizglobEditFieldLabel.Text = 'Kut drugi zglob';
            app.KutdrugizglobEditField = uieditfield(app.UIFigure, 'numeric');
            app.KutdrugizglobEditField.ValueChangedFcn = createCallbackFcn(app, @KutdrugizglobEditFieldValueChanged, true);
            app.PozicijaxEditFieldLabel = uilabel(app.UIFigure);
            app.PozicijaxEditFieldLabel.Text = 'Pozicija x';
            app.PozicijaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.PozicijayEditFieldLabel = uilabel(app.UIFigure);
            app.PozicijayEditFieldLabel.Text = 'Pozicija y';
            app.PozicijayEditField = uieditfield(app.UIFigure, 'numeric');
            app.OlovkakutEditFieldLabel = uilabel(app.UIFigure);
            app.OlovkakutEditFieldLabel.Text = 'Olovka kut';
            app.OlovkakutEditField = uieditfield(app.UIFigure, 'numeric');
            app.OlovkakutEditField.ValueChangedFcn = createCallbackFcn(app, @OlovkakutEditFieldValueChanged, true);
            app.PozicijaolovkeEditFieldLabel = uilabel(app.UIFigure);
            app.PozicijaolovkeEditFieldLabel.Text = 'Pozicija olovke';
            app.PozicijaolovkeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.RucnoButton = uiradiobutton(app.ButtonGroup);
            app.RucnoButton.Text = 'Rucno';
            app.RucnoButton.Value = true;
            app.AutonomnoButton = uiradiobutton(app.ButtonGroup);
            app.AutonomnoButton.Text = 'Autonomno';
            app.IskljucenoButton = uiradiobutton(app.ButtonGroup);
            app.IskljucenoButton.Text = 'Iskljuceno';
            app.PotvrdaunosapozicijeButton = uibutton(app.UIFigure, 'push');
            app.PotvrdaunosapozicijeButton.ButtonPushedFcn = createCallbackFcn(app, @PotvrdaunosapozicijeButtonPushed, true);
            app.PotvrdaunosapozicijeButton.Text = 'Potvrda unosa pozicije';
            app.PotvrdaunosakutevaButton = uibutton(app.UIFigure, 'push');
            app.PotvrdaunosakutevaButton.ButtonPushedFcn = createCallbackFcn(app, @PotvrdaunosakutevaButtonPushed, true);
            app.PotvrdaunosakutevaButton.Text = 'Potvrda unosa kuteva';
            app.KutdrugizglobSliderLabel = uilabel(app.UIFigure);
            app.KutdrugizglobSliderLabel.Text = 'Kut drugi zglob';
            app.KutdrugizglobSlider = uislider(app.UIFigure);
            app.KutdrugizglobSlider.ValueChangingFcn = createCallbackFcn(app, @KutdrugizglobSliderValueChanging, true);
            app.OlovkakutSliderLabel = uilabel(app.UIFigure);
            app.OlovkakutSliderLabel.Text = 'Olovka kut';
            app.OlovkakutSlider = uislider(app.UIFigure);
            app.OlovkakutSlider.ValueChangingFcn = createCallbackFcn(app, @OlovkakutSliderValueChanging, true);
            app.KutprvizglobSliderLabel = uilabel(app.UIFigure);
            app.KutprvizglobSliderLabel.Text = 'Kut prvi zglob';
            app.KutprvizglobSlider = uislider(app.UIFigure);
            app.KutprvizglobSlider.ValueChangingFcn = createCallbackFcn(app, @KutprvizglobSliderValueChanging, true);
            app.UnoskutevaLabel = uilabel(app.UIFigure);
            app.UnoskutevaLabel.Text = 'Unos kuteva';
            app.UnospozicijaLabel = uilabel(app.UIFigure);
            app.UnospozicijaLabel.Text = 'Unos pozicija';
            app.IznosikutevaLabel = uilabel(app.UIFigure);
            app.IznosikutevaLabel.Text = 'Iznosi kuteva';
            app.IznosipozicijaLabel = uilabel(app.UIFigure);
            app.IznosipozicijaLabel.Text = 'Iznosi pozicija';
            app.Kut1Label = uilabel(app.UIFigure);
            app.Kut1Label.Text = 'Kut 1';
            app.Kut2Label = uilabel(app.UIFigure);
            app.Kut2Label.Text = 'Kut 2';
            app.Kut3Label = uilabel(app.UIFigure);
            app.Kut3Label.Text = 'Kut 3';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z';
            app.zglobLabel = uilabel(app.UIFigure);
            app.zglobLabel.Text = '1. zglob';
            app.zglobLabel_2 = uilabel(app.UIFigure);
            app.zglobLabel_2.Text = '2. zglob';
            app.OlovkaLabel = uilabel(app.UIFigure);
            app.OlovkaLabel.Text = 'Olovka';
            app.XpozLabel = uilabel(app.UIFigure);
            app.XpozLabel.Text = 'X poz';
            app.YpozLabel = uilabel(app.UIFigure);
            app.YpozLabel.Text = 'Y poz';
            app.ZpozLabel = uilabel(app.UIFigure);
            app.ZpozLabel.Text = 'Z poz';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_prarob

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end