classdef app_prarob < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        VidislikuButton                matlab.ui.control.Button
        SpojiButton                    matlab.ui.control.Button
        ZabranjeniobjektiEditField     matlab.ui.control.EditField
        ZabranjeniobjektiEditFieldLabel  matlab.ui.control.Label
        ObjektizaspajanjeEditField     matlab.ui.control.EditField
        ObjektizaspajanjeEditFieldLabel  matlab.ui.control.Label
        TrenutnizLabel                 matlab.ui.control.Label
        TrenutniyLabel                 matlab.ui.control.Label
        TrenutnixLabel                 matlab.ui.control.Label
        TrenutnavrijednostLabel_3      matlab.ui.control.Label
        TrenutnavrijednostLabel_2      matlab.ui.control.Label
        TrenutnavrijednostLabel        matlab.ui.control.Label
        ZLabel                         matlab.ui.control.Label
        YLabel                         matlab.ui.control.Label
        XLabel                         matlab.ui.control.Label
        Kut3Label                      matlab.ui.control.Label
        Kut2Label                      matlab.ui.control.Label
        Kut1Label                      matlab.ui.control.Label
        KutprvogzglobaSlider           matlab.ui.control.Slider
        KutprvogzglobaSliderLabel      matlab.ui.control.Label
        KutolovkeSlider                matlab.ui.control.Slider
        KutolovkeSliderLabel           matlab.ui.control.Label
        KutdrugogzglobaSlider          matlab.ui.control.Slider
        KutdrugogzglobaSliderLabel     matlab.ui.control.Label
        PotvrdaunosakutevaButton       matlab.ui.control.Button
        PotvrdaunosapozicijeButton     matlab.ui.control.Button
        NacinradaButtonGroup           matlab.ui.container.ButtonGroup
        IskljucenoButton               matlab.ui.control.RadioButton
        AutonomnoButton                matlab.ui.control.RadioButton
        RucnoButton                    matlab.ui.control.RadioButton
        PozicijazEditField             matlab.ui.control.NumericEditField
        PozicijazEditFieldLabel        matlab.ui.control.Label
        KutolovkeEditField             matlab.ui.control.NumericEditField
        KutolovkeEditFieldLabel        matlab.ui.control.Label
        PozicijayEditField             matlab.ui.control.NumericEditField
        PozicijayEditFieldLabel        matlab.ui.control.Label
        PozicijaxEditField             matlab.ui.control.NumericEditField
        PozicijaxEditFieldLabel        matlab.ui.control.Label
        KutdrugogzglobaEditField       matlab.ui.control.NumericEditField
        KutdrugogzglobaEditFieldLabel  matlab.ui.control.Label
        KutprvogzglobaEditField        matlab.ui.control.NumericEditField
        KutprvogzglobaEditFieldLabel   matlab.ui.control.Label
        Image                          matlab.ui.control.Image
    end

    
    properties (Access = public)
        SimulinkModelName; % Description
        Kut1
        Kut2
        Kut3
        bboxes 
        labels
        image
        R_cam
        t_cam
        T_back
    end
    
    methods (Access = public)
    
    function setKut(app)
        set_param([app.SimulinkModelName, '/prvikut'], 'Value', num2str(app.KutprvogzglobaEditField.Value));
        set_param([app.SimulinkModelName, '/drugikut'], 'Value', num2str(app.KutdrugogzglobaEditField.Value));
        set_param([app.SimulinkModelName, '/trecikut'], 'Value', num2str(-app.KutolovkeEditField.Value));
        
    end
        
    function UpdateValues(app, fields, newVal)
            for i = 1:numel(fields)
                app.(fields{i}).Value = newVal;
            end
    end

        
    function UpdateText(app, fields, newVal)
            for i = 1:numel(fields)
                app.(fields{i}).Text = mat2str(newVal);
                drawnow;
            end
    end
    function UpdateK2(app, newVal)
        app.Kut2Label.Text = mat2str(newVal);
        drawnow;
    end
    function UpdateK1(app, newVal)
        app.Kut1Label.Text = mat2str(newVal);
        drawnow;
    end
     function UpdateK3(app, newVal)
        app.Kut3Label.Text = mat2str(newVal);
        drawnow;
    end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global hApp
            hApp = app
            
            app.SimulinkModelName = 'simulation_three_motors';
            ylimit = [-175, 175];
            xlimit = [0, 350];
            zlimit = [0, 40];
            app.PozicijazEditField.Limits = zlimit;
            app.PozicijaxEditField.Limits = xlimit;
            app.PozicijayEditField.Limits = ylimit;
            angleLimit1 = [-150, 150];    %
            angleLimit2 = [-163, 150];    % 
            angleLimit3 = [0, 90];    %kut olovke
            app.KutprvogzglobaSlider.Limits = angleLimit1;
            app.KutdrugogzglobaSlider.Limits = angleLimit2;
            app.KutolovkeSlider.Limits = angleLimit3;
            app.KutprvogzglobaEditField.Limits = angleLimit1;
            app.KutdrugogzglobaEditField.Limits = angleLimit2;
            app.KutolovkeEditField.Limits = angleLimit3;
            [R_cam1, t_cam1, ~] = calib_robot();
            app.R_cam = R_cam1;
            app.t_cam = t_cam1;
            app.ZabranjeniobjektiEditField.Enable = false;
            app.ObjektizaspajanjeEditField.Enable = false;
            app.SpojiButton.Enable = false;
            app.VidislikuButton.Enable = false;
        end

        % Selection changed function: NacinradaButtonGroup
        function NacinradaButtonGroupSelectionChanged(app, event)
            selectedButton = app.NacinradaButtonGroup.SelectedObject;
            if selectedButton == app.AutonomnoButton
                app.KutprvogzglobaEditField.Enable = false;
                app.KutprvogzglobaSlider.Enable = false;
                app.KutdrugogzglobaEditField.Enable = false;
                app.KutdrugogzglobaSlider.Enable = false;
                app.KutolovkeEditField.Enable = false;
                app.KutolovkeSlider.Enable = false;

                app.PozicijaxEditField.Enable = false;
                app.PozicijayEditField.Enable = false;
                app.PozicijazEditField.Enable = false;

                app.PotvrdaunosakutevaButton.Enable = false;
                app.PotvrdaunosapozicijeButton.Enable = false;
               

                app.Kut1Label.Enable = false;
                app.Kut2Label.Enable = false;
                app.Kut3Label.Enable = false;
                app.XLabel.Enable = false;
                app.YLabel.Enable = false;
                app.ZLabel.Enable = false;

                app.ZabranjeniobjektiEditField.Enable = true;
                app.ObjektizaspajanjeEditField.Enable = true;
                app.SpojiButton.Enable = false;
                app.VidislikuButton.Enable = true;
            end;

            if selectedButton == app.RucnoButton
                app.KutprvogzglobaEditField.Enable = true;
                app.KutprvogzglobaSlider.Enable = true;
                app.KutdrugogzglobaEditField.Enable = true;
                app.KutdrugogzglobaSlider.Enable = true;
                app.KutolovkeEditField.Enable = true;
                app.KutolovkeSlider.Enable = true;

                app.PozicijaxEditField.Enable = true;
                app.PozicijayEditField.Enable = true;
                app.PozicijazEditField.Enable = true;

                app.PotvrdaunosakutevaButton.Enable = true;
                app.PotvrdaunosapozicijeButton.Enable = true;
               

                app.Kut1Label.Enable = true;
                app.Kut2Label.Enable = true;
                app.Kut3Label.Enable = true;
                app.XLabel.Enable = true;
                app.YLabel.Enable = true;
                app.ZLabel.Enable = true;

                app.ZabranjeniobjektiEditField.Enable = false;
                app.ObjektizaspajanjeEditField.Enable = false;
                app.SpojiButton.Enable = false;
                app.VidislikuButton.Enable = false;
            end;

            if selectedButton == app.IskljucenoButton
                app.KutprvogzglobaEditField.Enable = false;
                app.KutprvogzglobaSlider.Enable = false;
                app.KutdrugogzglobaEditField.Enable = false;
                app.KutdrugogzglobaSlider.Enable = false;
                app.KutolovkeEditField.Enable = false;
                app.KutolovkeSlider.Enable = false;

                app.PozicijaxEditField.Enable = false;
                app.PozicijayEditField.Enable = false;
                app.PozicijazEditField.Enable = false;

                app.PotvrdaunosakutevaButton.Enable = false;
                app.PotvrdaunosapozicijeButton.Enable = false;
              

                app.Kut1Label.Enable = false;
                app.Kut2Label.Enable = false;
                app.Kut3Label.Enable = false;
                app.XLabel.Enable = false;
                app.YLabel.Enable = false;
                app.ZLabel.Enable = false;

                app.ZabranjeniobjektiEditField.Enable = false;
                app.ObjektizaspajanjeEditField.Enable = false;
                app.SpojiButton.Enable = false;
                app.VidislikuButton.Enable = false;
            end;
        end

        % Value changing function: KutprvogzglobaSlider
        function KutprvogzglobaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UpdateValues({'KutprvogzglobaEditField'}, changingValue);
        end

        % Value changing function: KutdrugogzglobaSlider
        function KutdrugogzglobaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UpdateValues({'KutdrugogzglobaEditField'}, changingValue);
        end

        % Value changing function: KutolovkeSlider
        function KutolovkeSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UpdateValues({'KutolovkeEditField'}, changingValue);
            app.KutolovkeEditField.Value = changingValue;
        end

        % Button pushed function: PotvrdaunosakutevaButton
        function PotvrdaunosakutevaButtonPushed(app, event)
            th1 = app.KutprvogzglobaEditField.Value;
            th2 = app.KutdrugogzglobaEditField.Value;
            th3 = app.KutolovkeEditField.Value;
            [x, y, z] = direct_kinematics(th1, th2, th3);
            if ((x < 150 && y < 75 && y > -75) || (z < 0 || z > 141.75) || (x<0 || x>350) || (y < -175 || y > 175))
                uialert(app.UIFigure, 'Pozicija vrha ulazi u zabranjeno podrucje', 'Nedozvoljena pozicija');
            else
                 app.setKut();
            end;
        end

        % Value changed function: KutprvogzglobaEditField
        function KutprvogzglobaEditFieldValueChanged(app, event)
            value = app.KutprvogzglobaEditField.Value;
            app.UpdateValues({'KutprvogzglobaSlider'}, value);
        end

        % Value changed function: KutdrugogzglobaEditField
        function KutdrugogzglobaEditFieldValueChanged(app, event)
            value = app.KutdrugogzglobaEditField.Value;
            app.UpdateValues({'KutdrugogzglobaSlider'}, value);
        end

        % Value changed function: KutolovkeEditField
        function KutolovkeEditFieldValueChanged(app, event)
            value = app.KutolovkeEditField.Value;
            app.UpdateValues({'KutolovkeSlider'}, value);
        end

        % Button pushed function: PotvrdaunosapozicijeButton
        function PotvrdaunosapozicijeButtonPushed(app, event)
            x = app.PozicijaxEditField.Value;
            y = app.PozicijayEditField.Value;
            z = app.PozicijazEditField.Value;
            if (x < 150 && y < 75 && y > -75)
                uialert(app.UIFigure, 'X i Y ulaze zabranjeno podrucje', 'Nedozvoljena pozicija');
            else
                if (y >= 0)
                    [t1, t2, t3] = solve_thetas2(x, y, z);
                else
                    [t1, t2, t3] = solve_thetas(x, y, z);
                end
                t3 = -t3;  
                set_param([app.SimulinkModelName, '/prvikut'], 'Value', num2str(t1));
                set_param([app.SimulinkModelName, '/drugikut'], 'Value', num2str(t2));
                set_param([app.SimulinkModelName, '/trecikut'], 'Value', num2str(t3));
            end
        end

        % Button pushed function: SpojiButton
        function SpojiButtonPushed(app, event)
    
            objekti = app.ObjektizaspajanjeEditField.Value;
            prepreke = app.ZabranjeniobjektiEditField.Value;
            [x_path, y_path] = processImage(app.image, objekti, prepreke, app.bboxes, app.labels);
            [x_path_reduced, y_path_reduced] = dump_points(x_path, y_path, 30, app.R_cam, app.t_cam);
            [theta1, theta2, ~] = solve_thetas3(x_path_reduced(1), y_path_reduced(1), 0);

            set_param([app.SimulinkModelName, '/prvikut'], 'Value', num2str(theta1));
            set_param([app.SimulinkModelName, '/drugikut'], 'Value', num2str(theta2));
            %set_param([app.SimulinkModelName, '/trecikut'], 'Value', '-90');                                                                                                                                                                                                                                                                                                                                                                                               nnnn                                                                                                                                                                                                                                                                                                                                                                                                                                                 nn                                                                                                                                                                                                                                                          nnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnbbbbbbbnbb
            pause(1);
            set_param([app.SimulinkModelName, '/trecikut'], 'Value', '0');
            pause(0.5);
            for i=1:length(x_path_reduced)
                
                [theta1, theta2, ~] = solve_thetas3(x_path_reduced(i), y_path_reduced(i), 0);
                %display(i,i);
                pause(0.01);
                set_param([app.SimulinkModelName, '/prvikut'], 'Value', num2str(theta1));
                set_param([app.SimulinkModelName, '/drugikut'], 'Value', num2str(theta2));
                %pause(0.1);
            end
            delete previous_results.mat;
            pause(3);
            set_param([app.SimulinkModelName, '/trecikut'], 'Value', num2str(-90));
            set_param([app.SimulinkModelName, '/prvikut'], 'Value', num2str(90));
            set_param([app.SimulinkModelName, '/drugikut'], 'Value', num2str(0));
        end

        % Button pushed function: VidislikuButton
        function VidislikuButtonPushed(app, event)
            set_param([app.SimulinkModelName, '/trecikut'], 'Value', num2str(-90));
            set_param([app.SimulinkModelName, '/prvikut'], 'Value', num2str(90));
            set_param([app.SimulinkModelName, '/drugikut'], 'Value', num2str(0));
            pause(0.05);
            app.image = kalibracija5();
            [app.bboxes, app.labels] = yoloDetect(app.image);
            app.SpojiButton.Enable = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'robot.png');
            app.KutprvogzglobaEditFieldLabel = uilabel(app.UIFigure);
            app.KutprvogzglobaEditFieldLabel.Text = 'Kut prvog zgloba';
            app.KutprvogzglobaEditField = uieditfield(app.UIFigure, 'numeric');
            app.KutprvogzglobaEditField.ValueChangedFcn = createCallbackFcn(app, @KutprvogzglobaEditFieldValueChanged, true);
            app.KutdrugogzglobaEditFieldLabel = uilabel(app.UIFigure);
            app.KutdrugogzglobaEditFieldLabel.Text = 'Kut drugog zgloba';
            app.KutdrugogzglobaEditField = uieditfield(app.UIFigure, 'numeric');
            app.KutdrugogzglobaEditField.ValueChangedFcn = createCallbackFcn(app, @KutdrugogzglobaEditFieldValueChanged, true);
            app.PozicijaxEditFieldLabel = uilabel(app.UIFigure);
            app.PozicijaxEditFieldLabel.Text = 'Pozicija x';
            app.PozicijaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.PozicijayEditFieldLabel = uilabel(app.UIFigure);
            app.PozicijayEditFieldLabel.Text = 'Pozicija y';
            app.PozicijayEditField = uieditfield(app.UIFigure, 'numeric');
            app.KutolovkeEditFieldLabel = uilabel(app.UIFigure);
            app.KutolovkeEditFieldLabel.Text = 'Kut olovke';
            app.KutolovkeEditField = uieditfield(app.UIFigure, 'numeric');
            app.KutolovkeEditField.ValueChangedFcn = createCallbackFcn(app, @KutolovkeEditFieldValueChanged, true);
            app.PozicijazEditFieldLabel = uilabel(app.UIFigure);
            app.PozicijazEditFieldLabel.Text = 'Pozicija z';
            app.PozicijazEditField = uieditfield(app.UIFigure, 'numeric');
            app.NacinradaButtonGroup = uibuttongroup(app.UIFigure);
            app.NacinradaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NacinradaButtonGroupSelectionChanged, true);
            app.NacinradaButtonGroup.Title = 'Nacin rada';
            app.RucnoButton = uiradiobutton(app.NacinradaButtonGroup);
            app.RucnoButton.Text = 'Rucno';
            app.RucnoButton.Value = true;
            app.AutonomnoButton = uiradiobutton(app.NacinradaButtonGroup);
            app.AutonomnoButton.Text = 'Autonomno';
            app.IskljucenoButton = uiradiobutton(app.NacinradaButtonGroup);
            app.IskljucenoButton.Text = 'Iskljuceno';
            app.PotvrdaunosapozicijeButton = uibutton(app.UIFigure, 'push');
            app.PotvrdaunosapozicijeButton.ButtonPushedFcn = createCallbackFcn(app, @PotvrdaunosapozicijeButtonPushed, true);
            app.PotvrdaunosapozicijeButton.Text = 'Potvrda unosa pozicije';
            app.PotvrdaunosakutevaButton = uibutton(app.UIFigure, 'push');
            app.PotvrdaunosakutevaButton.ButtonPushedFcn = createCallbackFcn(app, @PotvrdaunosakutevaButtonPushed, true);
            app.PotvrdaunosakutevaButton.Text = 'Potvrda unosa kuteva';
            app.KutdrugogzglobaSliderLabel = uilabel(app.UIFigure);
            app.KutdrugogzglobaSliderLabel.Text = 'Kut drugog zgloba';
            app.KutdrugogzglobaSlider = uislider(app.UIFigure);
            app.KutdrugogzglobaSlider.ValueChangingFcn = createCallbackFcn(app, @KutdrugogzglobaSliderValueChanging, true);
            app.KutolovkeSliderLabel = uilabel(app.UIFigure);
            app.KutolovkeSliderLabel.Text = 'Kut olovke';
            app.KutolovkeSlider = uislider(app.UIFigure);
            app.KutolovkeSlider.ValueChangingFcn = createCallbackFcn(app, @KutolovkeSliderValueChanging, true);
            app.KutprvogzglobaSliderLabel = uilabel(app.UIFigure);
            app.KutprvogzglobaSliderLabel.Text = 'Kut prvog zgloba';
            app.KutprvogzglobaSlider = uislider(app.UIFigure);
            app.KutprvogzglobaSlider.ValueChangingFcn = createCallbackFcn(app, @KutprvogzglobaSliderValueChanging, true);
            app.Kut1Label = uilabel(app.UIFigure);
            app.Kut1Label.Text = 'Kut 1';
            app.Kut2Label = uilabel(app.UIFigure);
            app.Kut2Label.Text = 'Kut 2';
            app.Kut3Label = uilabel(app.UIFigure);
            app.Kut3Label.Text = 'Kut 3';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z';
            app.TrenutnavrijednostLabel = uilabel(app.UIFigure);
            app.TrenutnavrijednostLabel.Text = 'Trenutna vrijednost ';
            app.TrenutnavrijednostLabel_2 = uilabel(app.UIFigure);
            app.TrenutnavrijednostLabel_2.Text = 'Trenutna vrijednost';
            app.TrenutnavrijednostLabel_3 = uilabel(app.UIFigure);
            app.TrenutnavrijednostLabel_3.Text = 'Trenutna vrijednost';
            app.TrenutnixLabel = uilabel(app.UIFigure);
            app.TrenutnixLabel.Text = 'Trenutni x';
            app.TrenutniyLabel = uilabel(app.UIFigure);
            app.TrenutniyLabel.Text = 'Trenutni y';
            app.TrenutnizLabel = uilabel(app.UIFigure);
            app.TrenutnizLabel.Text = 'Trenutni z';
            app.ObjektizaspajanjeEditFieldLabel = uilabel(app.UIFigure);
            app.ObjektizaspajanjeEditFieldLabel.Text = 'Objekti za spajanje';
            app.ObjektizaspajanjeEditField = uieditfield(app.UIFigure, 'text');
            app.ZabranjeniobjektiEditFieldLabel = uilabel(app.UIFigure);
            app.ZabranjeniobjektiEditFieldLabel.Text = 'Zabranjeni objekti';
            app.ZabranjeniobjektiEditField = uieditfield(app.UIFigure, 'text');
            app.SpojiButton = uibutton(app.UIFigure, 'push');
            app.SpojiButton.ButtonPushedFcn = createCallbackFcn(app, @SpojiButtonPushed, true);
            app.SpojiButton.Text = 'Spoji';
            app.VidislikuButton = uibutton(app.UIFigure, 'push');
            app.VidislikuButton.ButtonPushedFcn = createCallbackFcn(app, @VidislikuButtonPushed, true);
            app.VidislikuButton.Text = 'Vidi sliku';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_prarob

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end